classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        LampCos          matlab.ui.control.Lamp
        LampSin          matlab.ui.control.Lamp
        LampDouble       matlab.ui.control.Lamp
        ResultValue      matlab.ui.control.NumericEditField
        Label_3          matlab.ui.control.Label
        GetValueButton   matlab.ui.control.StateButton
        AddNum_2         matlab.ui.control.NumericEditField
        Label_2          matlab.ui.control.Label
        AddNum_1         matlab.ui.control.NumericEditField
        Label            matlab.ui.control.Label
        aEditField       matlab.ui.control.EditField
        aEditFieldLabel  matlab.ui.control.Label
        aSlider          matlab.ui.control.Slider
        aSliderLabel     matlab.ui.control.Label
        yx2Button        matlab.ui.control.Button
        ycosxButton      matlab.ui.control.Button
        ysinxButton      matlab.ui.control.Button
        UIAxes           matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ysinxButton
        function ysinxButtonPushed(app, event)
            % set Lmap

            app.LampDouble.Color = [1,0,0];
            app.LampCos.Color = [1,0,0];
            app.LampSin.Color = [0,1,0];
            
            x = linspace(1,2*pi,100);
            y = sin(x);
            plot(app.UIAxes,x,y);
            xlabel(app.UIAxes, "x");
            ylabel(app.UIAxes, "y = sin(x)");
            title(app.UIAxes, "y = sin(x)的函数图像");
            
            app.UIAxes.XLim = [ 0 , 2*pi ];
            app.UIAxes.YLim = [ -1, 1 ];
        end

        % Button pushed function: ycosxButton
        function ycosxButtonPushed(app, event)
            % set Lmap
            app.LampDouble.Color = [1,0,0];
            app.LampCos.Color = [0,1,0];
            app.LampSin.Color = [1,0,0];
            
            x = linspace(1,2*pi,100);
            y = cos(x);
            
            plot(app.UIAxes,x,y);
            xlabel(app.UIAxes, "x");
            ylabel(app.UIAxes, "y = cos(x)");
            title(app.UIAxes, "y = cos(x)的函数图像");
            
            app.UIAxes.XLim = [ 0 , 2*pi ];
            app.UIAxes.YLim = [ -1, 1 ];
        end

        % Button pushed function: yx2Button
        function yx2ButtonPushed(app, event)
            % set lamp
            app.LampDouble.Color = [0,1,0];
            app.LampCos.Color = [1,0,0];
            app.LampSin.Color = [1,0,0];
            
            x = linspace(-10,10,1000);
            a = app.aSlider.Value;
            y = a*x.^2;
            
            plot(app.UIAxes,x,y);
            xlabel(app.UIAxes, "x");
            ylabel(app.UIAxes, "y = a * x ^ 2");
            title(app.UIAxes, "y = a * x ^ 2的函数图像");
            
            app.UIAxes.XLim = [ -10 , 10 ];
            app.UIAxes.YLim = [ 0, 100 ];
        end

        % Value changed function: aSlider
        function aSliderValueChanged(app, event)
            value = app.aSlider.Value;
            str = num2str(value);
            app.aEditField.Value = str;
            yx2ButtonPushed(app,event);
            %放置到标签
        end

        % Value changed function: aEditField
        function aEditFieldValueChanged(app, event)
            value = app.aEditField.Value;
            str = str2num(value);
            app.aSlider.Value =  str;
            yx2ButtonPushed(app,event);
        end

        % Value changed function: GetValueButton
        function getSum(app, event)
            value1 = app.AddNum_1.Value;
            value2 = app.AddNum_2.Value;
            resultstr = value1 + value2;
            app.ResultValue.Value = resultstr;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.ysinxButton = uibutton(app.UIFigure, 'push');
            app.ysinxButton.ButtonPushedFcn = createCallbackFcn(app, @ysinxButtonPushed, true);
            app.ysinxButton.Text = {'     y = sin (x)'; ''};
            app.ycosxButton = uibutton(app.UIFigure, 'push');
            app.ycosxButton.ButtonPushedFcn = createCallbackFcn(app, @ycosxButtonPushed, true);
            app.ycosxButton.Text = {'     y = cos (x)'; ''};
            app.yx2Button = uibutton(app.UIFigure, 'push');
            app.yx2Button.ButtonPushedFcn = createCallbackFcn(app, @yx2ButtonPushed, true);
            app.yx2Button.Text = '     y = x^2';
            app.aSliderLabel = uilabel(app.UIFigure);
            app.aSliderLabel.Text = '设置a的值';
            app.aSlider = uislider(app.UIFigure);
            app.aSlider.Limits = [0.1 10];
            app.aSlider.ValueChangedFcn = createCallbackFcn(app, @aSliderValueChanged, true);
            app.aSlider.Value = 1;
            app.aEditFieldLabel = uilabel(app.UIFigure);
            app.aEditFieldLabel.Text = 'a的值';
            app.aEditField = uieditfield(app.UIFigure, 'text');
            app.aEditField.ValueChangedFcn = createCallbackFcn(app, @aEditFieldValueChanged, true);
            app.aEditField.Value = '1';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '加数1';
            app.AddNum_1 = uieditfield(app.UIFigure, 'numeric');
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '加数2';
            app.AddNum_2 = uieditfield(app.UIFigure, 'numeric');
            app.GetValueButton = uibutton(app.UIFigure, 'state');
            app.GetValueButton.ValueChangedFcn = createCallbackFcn(app, @getSum, true);
            app.GetValueButton.Text = '=';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '结果';
            app.ResultValue = uieditfield(app.UIFigure, 'numeric');
            app.LampDouble = uilamp(app.UIFigure);
            app.LampSin = uilamp(app.UIFigure);
            app.LampCos = uilamp(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end